classdef ProcessingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ImportTab                      matlab.ui.container.Tab
        LogsheetPathEditFieldLabel     matlab.ui.control.Label
        LogsheetPathEditField          matlab.ui.control.EditField
        ProjectNameEditFieldLabel      matlab.ui.control.Label
        ProjectNameEditField           matlab.ui.control.EditField
        ProjectPathEditFieldLabel      matlab.ui.control.Label
        ProjectPathEditField           matlab.ui.control.EditField
        RunButton                      matlab.ui.control.Button
        RedoCheckBox                   matlab.ui.control.CheckBox
        AddDataTypesCheckBox           matlab.ui.control.CheckBox
        UpdateMetadataOnlyCheckBox     matlab.ui.control.CheckBox
        ReloadExistingDataCheckBox     matlab.ui.control.CheckBox
        OpenimportSettingsButton       matlab.ui.control.Button
        ProcessTab                     matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        FunctionNamesLabel             matlab.ui.control.Label
        FunctionNamesTextArea          matlab.ui.control.TextArea
        FunctionGroupEditFieldLabel    matlab.ui.control.Label
        FunctionGroupEditField         matlab.ui.control.NumericEditField
        OpenspecifyTrialsButton        matlab.ui.control.Button
        AddFunctionstoGroupSaveButton  matlab.ui.control.Button
        ClickoutsideboxwhendoneLabel   matlab.ui.control.Label
        LoadFunctionNamesinGroupFromFileButton  matlab.ui.control.Button
        NewProcessingFunctionTrialLevelButton  matlab.ui.control.Button
        NewProcessingFunctionSubjectLevelButton  matlab.ui.control.Button
        NewProcessingFunctionProjectLevelButton  matlab.ui.control.Button
        RunTab                         matlab.ui.container.Tab
        DropDownLabel                  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        RedoFcnLabel                   matlab.ui.control.Label
        CheckBox_1                     matlab.ui.control.CheckBox
        RunGroupButton                 matlab.ui.control.Button
        SaveGroupButton                matlab.ui.control.Button
        RunAllButton                   matlab.ui.control.Button
        SaveAllButton                  matlab.ui.control.Button
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_6                     matlab.ui.control.CheckBox
        CheckBox_7                     matlab.ui.control.CheckBox
        CheckBox_8                     matlab.ui.control.CheckBox
        CheckBox_9                     matlab.ui.control.CheckBox
        CheckBox_10                    matlab.ui.control.CheckBox
        FunctionNamesTextArea_2Label   matlab.ui.control.Label
        FunctionNamesTextArea_2        matlab.ui.control.TextArea
        PlotTab                        matlab.ui.container.Tab
        AllPlottingFunctionNamesTextAreaLabel  matlab.ui.control.Label
        AllPlottingFunctionNamesTextArea  matlab.ui.control.TextArea
        SelectaFunctionDropDownLabel   matlab.ui.control.Label
        SelectaFunctionDropDown        matlab.ui.control.DropDown
        MultipleTrialsCheckBox         matlab.ui.control.CheckBox
        AxisLabelsCheckBox             matlab.ui.control.CheckBox
        PlotButton                     matlab.ui.control.Button
        SaveFigCheckBox                matlab.ui.control.CheckBox
        SavePNGCheckBox                matlab.ui.control.CheckBox
        SavetransparentPNGCheckBox     matlab.ui.control.CheckBox
        XLimitsEditFieldLabel          matlab.ui.control.Label
        XLimitsEditField               matlab.ui.control.EditField
        YLimitsEditFieldLabel          matlab.ui.control.Label
        YLimitsEditField               matlab.ui.control.EditField
        ZLimitsEditFieldLabel          matlab.ui.control.Label
        ZLimitsEditField               matlab.ui.control.EditField
        NewPlottingFunctionButton      matlab.ui.control.Button
        RootSaveFolderEditFieldLabel   matlab.ui.control.Label
        RootSaveFolderEditField        matlab.ui.control.EditField
        CurrentSaveSubfolderEditFieldLabel  matlab.ui.control.Label
        CurrentSaveSubfolderEditField  matlab.ui.control.EditField
        StatisticsTab                  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        CurrGroupFcnNames % Function names for one individual set of functions.
        FcnSet % Number identifying one set of functions
        allFcnNames % Names of all functions to run (across all groups)
        projectName % Project name from projectStruct.Info.ProjectName
        procFunc % Processing function names
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RedoCheckBox.Value=0; % Initialize Redo to be unchecked.
            evalin('base','projectStruct.Info.Flags.Redo=0;');
            app.AddDataTypesCheckBox.Value=0; % AddDataTypes begins unchecked.
            evalin('base','projectStruct.Info.Flags.AddDataTypes=0;');
            app.UpdateMetadataOnlyCheckBox.Value=0; % UpdateMetadata begins unchecked.
            evalin('base','projectStruct.Info.Flags.UpdateMetadata=0;');
            app.ReloadExistingDataCheckBox.Value=0; % ReloadExistingData begins unchecked.
            evalin('base','projectStruct.Info.Flags.ReloadExistingData=0;');
            app.FcnSet=0; % Initialize group number to be 0.
            app.CurrGroupFcnNames{1}=''; % Initialize current group function names to be empty char.
        end

        % Value changed function: LogsheetPathEditField
        function LogsheetPathEditFieldValueChanged(app, event)
            value = app.LogsheetPathEditField.Value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.LogsheetPath=tempName;');
        end

        % Value changed function: ProjectNameEditField
        function ProjectNameEditFieldValueChanged(app, event)
            value = app.ProjectNameEditField.Value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.ProjectName=tempName;');
            app.projectName=value;
        end

        % Value changed function: ProjectPathEditField
        function ProjectPathEditFieldValueChanged(app, event)
            value = app.ProjectPathEditField.Value;
            assignin('base','tempName',value);
            evalin('base','projectStruct.Info.ProjectPath=tempName;');
            cd(value); % Set current directory to project path.
        end

        % Value changed function: RedoCheckBox
        function RedoCheckBoxValueChanged(app, event)
            value = app.RedoCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.Redo=tempCheckbox;');
        end

        % Value changed function: AddDataTypesCheckBox
        function AddDataTypesCheckBoxValueChanged(app, event)
            value = app.AddDataTypesCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.AddDataTypes=tempCheckbox;');
        end

        % Value changed function: UpdateMetadataOnlyCheckBox
        function UpdateMetadataOnlyCheckBoxValueChanged(app, event)
            value = app.UpdateMetadataOnlyCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.UpdateMetadata=tempCheckbox;');
        end

        % Value changed function: ReloadExistingDataCheckBox
        function ReloadExistingDataCheckBoxValueChanged(app, event)
            value = app.ReloadExistingDataCheckBox.Value;
            assignin('base','tempCheckbox',value);
            evalin('base','projectStruct.Info.Flags.ReloadExistingData=tempCheckbox;');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            evalin('base','projectStruct=importProject(projectStruct);'); % Run the import.
        end

        % Button pushed function: OpenspecifyTrialsButton
        function OpenspecifyTrialsButtonPushed(app, event)
            if app.FcnSet==0 || isequal(app.CurrGroupFcnNames{1},'')
                return;
            end
            evalin('base','cd(projectStruct.Info.ProjectPath)'); % Set the current directory to the project folder.
            currCD=cd('SpecifyTrials'); % Set current directory to SpecifyTrials folder.
            listing=dir; % Get the contents of the current folder
            evalin('base',"currName=['specifyTrials_' projectStruct.Info.ProjectName '_' num2str(fcnSet) '.m'];");
            for i=1:length(listing) % Iterate through all contents
                if ~isfolder(listing(i).name) % Make sure it's a file, not a folder.
                    assignin('base','tempName',listing(i).name); % Assign the current file name to base.
                    if evalin('base','isequal(tempName,currName)') % If this file name exists already.
                        alrdyDone=1; % Flag to indicate not to overwrite.
                        selection = uiconfirm(app.UIFigure,'Already Exists. Overwrite?','Confirm Overwrite',...
                            'Icon','warning');
                        if isequal(selection,'OK') % Overwrite
                            alrdyDone=0;
                        end
                    end
                end
            end
            if alrdyDone==0
                evalin('base',char("copyfile('specifyTrials_Template.m',currName);")); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [inclStruct]=specifyTrials_' projectStruct.Info.ProjectName '_' num2str(fcnSet) '(logsheetPath,projectPath,projectName)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
            cd(currCD);
        end

        % Value changed function: FunctionNamesTextArea
        function FunctionNamesTextAreaValueChanged(app, event)
            value = app.FunctionNamesTextArea.Value;
            assignin('base','fcnNames',value);
            app.CurrGroupFcnNames=value;
            disp('Functions in Current Group Changed To:');
            app.CurrGroupFcnNames
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Are You Sure You Want to Close?','Confirm Close',...
                'Icon','warning');
            if isequal(selection,'OK')
                delete(app);
            end
        end

        % Button pushed function: AddFunctionstoGroupSaveButton
        function AddFunctionstoGroupSaveButtonPushed(app, event)
            if app.FcnSet==0 || isequal(app.CurrGroupFcnNames{1},'')
                disp('NEED TO SET UP A GROUP FIRST!');
                beep;
                return;
            end
            % Store the current set of functions into the master list. Will overwrite the group.
            app.allFcnNames{app.FcnSet}=app.CurrGroupFcnNames;
            disp(['Functions Added to Group ' num2str(app.FcnSet) ':']);
            app.CurrGroupFcnNames % Display the function names in this group added to the master list
            % Save the group number & function names to file.
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2
                f=fopen(currName,'w'); % Create or open file.
                fclose(f); % Close file, because fileread doesn't need it.
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file line by line
            fileLength=length(A); % Number of lines already in the file.
            sameGroupExists=0; % Initialize that the group does not exist in this file.
            higherGroupExists=0;
            lowerGroupExists=0;
            if fileLength==1 % Starting a new file.
                lineNum=0; % Ensure that writing to the file begins at the first line.
                B={};                
                for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                    if i==1 % Add group number at beginning of group
                        lineNum=lineNum+1;
                        B{lineNum}=['Group ' num2str(app.FcnSet) ':'];
                    end
                    lineNum=lineNum+1;
                    B{lineNum}=app.CurrGroupFcnNames{i}; % Add each function name to its own line.
                end
            else
                % Check if overwriting a group or adding a group. If adding, ensure it goes at proper position.
                % Overwriting.
                for i=1:length(A)
                    if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.FcnSet<10 && isequal(str2double(A{i}(end-1)),app.FcnSet)) || (app.FcnSet>=10 && app.FcnSet<100 && isequal(str2double(A{i}(end-2:end-1)),app.FcnSet)))
                        sameGroupExists=1; % Group number found in the file.
                        startInsertLine=i; % Line number of the group number
                    end
                    if sameGroupExists==1 % Check for first empty line after the group
                        if isequal(A{i},'')
                            lastLine=i; % Line number of the empty line after the group
                            break;
                        end
                    end
                end
                if sameGroupExists==1 % Replace a previously written group
                    % Copy over everything before the overwritten group
                    if startInsertLine>1
                        B=A(1:startInsertLine-1); % Ends with a '' just before the group name
                    else
                        B={};
                    end
                    % Overwrite the group
                    lineNum=startInsertLine-1; % The line with a '' just before the group name
                    for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                        if i==1 % Add group number at beginning of group
                            lineNum=lineNum+1;
                            B{lineNum}=['Group ' num2str(app.FcnSet) ':'];
                        end
                        lineNum=lineNum+1;
                        B{lineNum}=app.CurrGroupFcnNames{i}; % Add each function name to its own line.
                    end
                    % Add in the rest of the group function names, if any.
                    if lastLine<length(A) % Check if there are any more groups
                        B=horzcat(B,A(lastLine:end));
                    end                    
                elseif sameGroupExists==0
                    % Adding new group.
                    % Case 1: Group number lower than all existing groups (goes at start of file)
                    % Case 2: Group number in middle of all existing groups (goes in middle of file)
                    % Case 3: Group number larger than all existing groups (goes at end of file)
                    % Check for lower groups
                    for i=1:length(A)
                        if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.FcnSet<10 && str2double(A{i}(end-1))<app.FcnSet) || (app.FcnSet>=10 && app.FcnSet<100 && str2double(A{i}(end-2:end-1))<app.FcnSet))
                            lowerGroupExists=1; % Group number found in the file.
                        end
                        % Check for higher groups
                        if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.FcnSet<10 && str2double(A{i}(end-1))>app.FcnSet) || (app.FcnSet>=10 && app.FcnSet<100 && str2double(A{i}(end-2:end-1))>app.FcnSet))
                            higherGroupExists=1; % Group number found in the file.
                            firstLine=i; % Line number of first function of the higher group
                        end
                    end
                    % Case 1 and 3
                    if (higherGroupExists==1 && lowerGroupExists~=1) || (higherGroupExists~=1 && lowerGroupExists==1)
                        % Write the group to file
                        lineNum=0; % The line with a '' just before the group name
                        for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                            if i==1 % Add group number at beginning of group
                                lineNum=lineNum+1;
                                B{lineNum}=['Group ' num2str(app.FcnSet) ':'];
                            end
                            lineNum=lineNum+1;
                            B{lineNum}=app.CurrGroupFcnNames{i}; % Add each function name to its own line.
                        end
                        if higherGroupExists==1 && lowerGroupExists~=1 % Case 1
                            B{lineNum+1}='';
                            B=horzcat(B,A); % Append A to B because all groups are higher than the current.
                        elseif higherGroupExists~=1 && lowerGroupExists==1 % Case 3
                            B=horzcat(A,B); % Append B to A because all groups are lower than the the current.
                        end
                    end
                    % Case 2
                    if higherGroupExists==1 && lowerGroupExists==1
                        B=A(1:firstLine-1); % Set B to be everything in A up to and including the empty line before the new group.
                        lineNum=firstLine-1; % The '' line before the higher group.
                        for i=1:length(app.CurrGroupFcnNames) % Iterate through all function names in current group.
                            if i==1 % Add group number at beginning of group
                                lineNum=lineNum+1;
                                B{lineNum}=['Group ' num2str(app.FcnSet) ':'];
                            end
                            lineNum=lineNum+1;
                            B{lineNum}=app.CurrGroupFcnNames{i}; % Add each function name to its own line.
                        end
                        B=horzcat(B,A(firstLine-1:end)); % Append the rest of A to the file with the new group.
                    end
                end
            end
            fid=fopen(currName,'w');
            fprintf(fid,'%s\n',B{:});
            fclose(fid);
        end

        % Value changed function: FunctionGroupEditField
        function FunctionGroupEditFieldValueChanged(app, event)
            value = app.FunctionGroupEditField.Value;
            assignin('base','fcnSet',value); % Assign the function group number specified to base.
            app.FcnSet=value; % Store the function group number.
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab; % Called when switching from Setup to Run
            if ~isequal(selectedTab.Title,'Run') % In the Run tab
                return;
            end
            currName=['functionNames_' app.projectName '.txt'];
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                groupList={'NULL'};
                app.allFcnNames=groupList;
            else
                % Iterate through the number of processing function groups to set up the drop down list.
                newMax=0;
                currName=['functionNames_' app.projectName '.txt'];
                funcCount=0;
                if exist(currName,'file')~=2 % Check that a functionNames file exists.
                    disp('No function names file for this project!');
                end
                A=regexp(fileread(currName),'\n','split'); % Read the file.
                for i=1:length(A)
                    if (length(A{i})>5 && isequal(A{i}(1:5),'Group'))
                        if regexp(A{i}(end-2),'[0-9]')
                            fcnSet=A{i}(end-2:end-1);
                        else
                            fcnSet=A{i}(end-1);
                        end
                        fcnSet=str2double(fcnSet);
                    elseif ~isequal(A{i},'') % This line is a function name.
                        funcCount=funcCount+1;
                        app.procFunc(funcCount).Name=A{i}; % Add function name
                        app.procFunc(funcCount).SpecifyTrialsCount=fcnSet; % Add function group number
                        if fcnSet>newMax
                            newMax=fcnSet;
                        end
                    end
                end
                groupList=cell(newMax,1); % Initialize list of groups.
                for i=1:length(groupList)
                    groupList{i,1}=['Group ' num2str(i)];
                end
                app.DropDown.Items=groupList; % Set dropdown list according to the number of groups.
                % Set the checkboxes to be set to match the current group.
                currDropdownVal = app.DropDown.Value;
                if ~isequal(currDropdownVal,'NULL')
                    groupNum=str2double(currDropdownVal(end)); % Group number
                else
                    groupNum=1;
                end
                % Initialize all of the Redo flags to be 0.
                funcCount=0;
                currName=['functionNames_' app.projectName '.txt'];
                A=regexp(fileread(currName),'\n','split'); % Read the file.
                for i=1:length(A)
                    if (length(A{i})>5 && isequal(A{i}(1:5),'Group'))
                        if regexp(A{i}(end-2),'[0-9]')
                            fcnSet=A{i}(end-2:end-1);
                        else
                            fcnSet=A{i}(end-1);
                        end
                    elseif ~isequal(A{i},'') % This line is a function name.
                        funcCount=funcCount+1;                        
                        app.procFunc(funcCount).Redo=0;                                              
                    end
                end                
                currGroupNames=app.allFcnNames{groupNum}; % List of current function names.
                for i=1:10 % Iterate over all labels.
                    app.(['CheckBox_' num2str(i)]).Value=0; % By default Redo is unchecked.
                    if i<=length(currGroupNames) && ~isequal(currGroupNames,'NULL') % Check if this group exists.
%                         app.(['Fcn' num2str(i) 'Label']).Visible='On';
%                         app.(['Fcn' num2str(i) 'Label']).Text=currGroupNames{i}; % Set the label to be the current function name.
                        app.(['CheckBox_' num2str(i)]).Visible='On';
                    else
                        app.(['CheckBox_' num2str(i)]).Visible='Off';
%                         app.(['Fcn' num2str(i) 'Label']).Visible='Off';
                    end
                end
            end
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            groupNum=str2double(value(end)); % Group number
            currGroupNames=app.allFcnNames{groupNum}; % List of current function names.
            for i=1:10 % Iterate over all labels.
                app.(['CheckBox_' num2str(i)]).Value=0; % By default Redo is unchecked.
                if i<=length(currGroupNames) % Check if this group exists.
%                     app.(['Fcn' num2str(i) 'Label']).Visible='On';
%                     app.(['Fcn' num2str(i) 'Label']).Text=currGroupNames{i}; % Set the label to be the current function name.
                    app.(['CheckBox_' num2str(i)]).Visible='On';
                else
                    app.(['CheckBox_' num2str(i)]).Visible='Off';
%                     app.(['Fcn' num2str(i) 'Label']).Visible='Off';
                end
            end
            
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            value = app.CheckBox_1.Value;
            % Need to associate the value of this checkbox with the corresponding function, and store it to be run later.
        end

        % Button pushed function: OpenimportSettingsButton
        function OpenimportSettingsButtonPushed(app, event)
            if evalin('base',"exist(['importSettings' projectStruct.Info.ProjectName]);")==2 % Check if file already exists.
                evalin('base',"edit(['importSettings' projectStruct.Info.ProjectName]);"); % Open file. Never overwrites an existing file.
            else
                % Make & format the file if it does not already exist (based on template).
                evalin('base',"currName=['importSettings' projectStruct.Info.ProjectName '.m'];");
                evalin('base',"copyfile('importSettingsTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [ProjHelper,dataTypes,segment]=' currName(1:end-2) '(subjectListInStruct,markerName)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
        end

        % Button pushed function: 
        % NewProcessingFunctionTrialLevelButton
        function NewProcessingFunctionTrialLevelButtonPushed(app, event)
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            evalin('base','tempName=tempName{1}');
            evalin('base',"currName=[tempName '.m'];"); % Prepend 'AACUSTOM' label to the function name (indicating it's not a downloaded function).
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                evalin('base',"copyfile('trialTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [dataStruct]=' currName(1:end-2) '(projInfo,subjInfo,dataStruct,args)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
        end

        % Button pushed function: 
        % NewProcessingFunctionSubjectLevelButton
        function NewProcessingFunctionSubjectLevelButtonPushed(app, event)
            evalin('base',"tempName=inputdlg('Function name');"); % Ask for user input to name the function.
            evalin('base','tempName=tempName{1}');
            evalin('base',"currName=[tempName '.m'];"); % Prepend 'AACUSTOM' label to the function name (indicating it's not a downloaded function).
            listing=dir; % Get the contents of the current folder
            alrdyDone=0;
            for i=1:length(listing)
                if ~isfolder(listing(i).name)
                    assignin('base','currFilename',listing(i).name);
                    if evalin('base','isequal(currFilename,tempName)')
                        alrdyDone=1; % Indicates that this file already exists.
                    end
                end
            end
            if alrdyDone==0
                evalin('base',"copyfile('subjectTemplate.m',[currName]);"); % Copy the template specifyTrials file
                evalin('base',"A=regexp(fileread(currName),'\n','split');"); % Get contents of new file, each line one cell element
                evalin('base',"A{1}=['function [dataStruct]=' currName(1:end-2) '(projInfo,subjInfo,dataStruct,args)'];"); % Change the first line to the new function name.
                evalin('base',"fid=fopen(currName,'w');"); % Open the file.
                evalin('base',"fprintf(fid,'%s\n',A{:});"); % Rewrite the file.
                evalin('base','fclose(fid);'); % Close the file.
            end
            evalin('base','edit(currName);'); % Open the file to edit.
        end

        % Button pushed function: 
        % LoadFunctionNamesinGroupFromFileButton
        function LoadFunctionNamesinGroupFromFileButtonPushed(app, event)
            currName=['functionNames_' app.projectName '.txt'];            
            if isempty(app.projectName)
                disp('Forgot to specify project name!');
                return;
            end
            if exist(currName,'file')==2 % Check that a functionNames file exists.
                A=regexp(fileread(currName),'\n','split'); % Read the file.
                sameGroupExists=0; % Initialize that the group does not exist in this file.
                for i=1:length(A)
                    if (length(A{i})>5 && isequal(A{i}(1:5),'Group')) && ((app.FcnSet<10 && isequal(str2double(A{i}(end-1)),app.FcnSet)) || (app.FcnSet>=10 && app.FcnSet<100 && isequal(str2double(A{i}(end-2:end-1)),app.FcnSet)))
                        sameGroupExists=1; % Group number found in the file.
                        firstFcnLine=i+1;
                    end
                    if sameGroupExists==1 % Check for first empty line after the group
                        if isequal(A{i},'')
                            lastFcnLine=i-1;
                            break;
                        elseif i==length(A)
                            lastFcnLine=i;
                        end
                    end
                end
                if sameGroupExists==0
                    disp('Wrong group number!');
                    return;
                end
                % Put the function names into the text area
                app.FunctionNamesTextArea.Value=A{firstFcnLine:lastFcnLine};
                app.CurrGroupFcnNames=A(firstFcnLine:lastFcnLine); % Store current function names to app property.
                app.allFcnNames{app.FcnSet}=app.CurrGroupFcnNames; % Store the current function names to the master list.
            else
                disp('No function names file for this project!');
            end
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
            currName=['functionNames_' app.projectName '.txt'];
            funcCount=0;
            if exist(currName,'file')~=2 % Check that a functionNames file exists.
                disp('No function names file for this project!');
            end
            A=regexp(fileread(currName),'\n','split'); % Read the file.
            for i=1:length(A)
                if (length(A{i})>5 && isequal(A{i}(1:5),'Group'))
                    if regexp(A{i}(end-2),'[0-9]')
                        fcnSet=A{i}(end-2:end-1);
                    else
                        fcnSet=A{i}(end-1);
                    end
                    fcnSet=str2double(fcnSet);
                elseif ~isequal(A{i},'') % This line is a function name.
                    funcCount=funcCount+1;                    
                    app.procFunc(funcCount).Name=A{i}; % Add function name
                    app.procFunc(funcCount).SpecifyTrialsCount=fcnSet; % Add function group number
                end                
            end
            assignin('base','procFunc',app.procFunc); % Assign the processing function names & metadata to the base workspace.
            evalin('base','[projectStruct]=processData(projectStruct,procFunc);'); % Run the data processing.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.LogsheetPathEditFieldLabel = uilabel(app.ImportTab);
            app.LogsheetPathEditFieldLabel.Text = 'Logsheet Path';
            app.LogsheetPathEditField = uieditfield(app.ImportTab, 'text');
            app.LogsheetPathEditField.ValueChangedFcn = createCallbackFcn(app, @LogsheetPathEditFieldValueChanged, true);
            app.LogsheetPathEditField.Value = 'Full Path to Logsheet Excel File';
            app.ProjectNameEditFieldLabel = uilabel(app.ImportTab);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.ImportTab, 'text');
            app.ProjectNameEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectNameEditFieldValueChanged, true);
            app.ProjectNameEditField.Value = 'Project Name';
            app.ProjectPathEditFieldLabel = uilabel(app.ImportTab);
            app.ProjectPathEditFieldLabel.Text = 'Project Path';
            app.ProjectPathEditField = uieditfield(app.ImportTab, 'text');
            app.ProjectPathEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectPathEditFieldValueChanged, true);
            app.ProjectPathEditField.Value = 'Full Path to Project Processing Folder';
            app.RunButton = uibutton(app.ImportTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RedoCheckBox = uicheckbox(app.ImportTab);
            app.RedoCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedoCheckBoxValueChanged, true);
            app.RedoCheckBox.Text = 'Redo';
            app.AddDataTypesCheckBox = uicheckbox(app.ImportTab);
            app.AddDataTypesCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddDataTypesCheckBoxValueChanged, true);
            app.AddDataTypesCheckBox.Text = 'Add Data Types';
            app.UpdateMetadataOnlyCheckBox = uicheckbox(app.ImportTab);
            app.UpdateMetadataOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateMetadataOnlyCheckBoxValueChanged, true);
            app.UpdateMetadataOnlyCheckBox.Text = 'Update Metadata Only';
            app.ReloadExistingDataCheckBox = uicheckbox(app.ImportTab);
            app.ReloadExistingDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReloadExistingDataCheckBoxValueChanged, true);
            app.ReloadExistingDataCheckBox.Text = 'Reload Existing Data';
            app.OpenimportSettingsButton = uibutton(app.ImportTab, 'push');
            app.OpenimportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenimportSettingsButtonPushed, true);
            app.OpenimportSettingsButton.Text = 'Open importSettings';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.TabGroup2 = uitabgroup(app.ProcessTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.SetupTab = uitab(app.TabGroup2);
            app.SetupTab.Title = 'Setup';
            app.Label = uilabel(app.SetupTab);
            app.Label.Text = 'Group functions together based on what order they''re run and which set of trials they''re run on';
            app.FunctionNamesLabel = uilabel(app.SetupTab);
            app.FunctionNamesLabel.Text = 'Function Names';
            app.FunctionNamesTextArea = uitextarea(app.SetupTab);
            app.FunctionNamesTextArea.ValueChangedFcn = createCallbackFcn(app, @FunctionNamesTextAreaValueChanged, true);
            app.FunctionGroupEditFieldLabel = uilabel(app.SetupTab);
            app.FunctionGroupEditFieldLabel.Text = 'Function Group #';
            app.FunctionGroupEditField = uieditfield(app.SetupTab, 'numeric');
            app.FunctionGroupEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionGroupEditFieldValueChanged, true);
            app.OpenspecifyTrialsButton = uibutton(app.SetupTab, 'push');
            app.OpenspecifyTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenspecifyTrialsButtonPushed, true);
            app.OpenspecifyTrialsButton.Text = 'Open specifyTrials';
            app.AddFunctionstoGroupSaveButton = uibutton(app.SetupTab, 'push');
            app.AddFunctionstoGroupSaveButton.ButtonPushedFcn = createCallbackFcn(app, @AddFunctionstoGroupSaveButtonPushed, true);
            app.AddFunctionstoGroupSaveButton.Text = 'Add Functions to Group & Save';
            app.ClickoutsideboxwhendoneLabel = uilabel(app.SetupTab);
            app.ClickoutsideboxwhendoneLabel.Text = 'Click outside box when done.';
            app.LoadFunctionNamesinGroupFromFileButton = uibutton(app.SetupTab, 'push');
            app.LoadFunctionNamesinGroupFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFunctionNamesinGroupFromFileButtonPushed, true);
            app.LoadFunctionNamesinGroupFromFileButton.Text = 'Load Function Names in Group From File';
            app.NewProcessingFunctionTrialLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionTrialLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewProcessingFunctionTrialLevelButtonPushed, true);
            app.NewProcessingFunctionTrialLevelButton.Text = 'New Processing Function: Trial Level';
            app.NewProcessingFunctionSubjectLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionSubjectLevelButton.ButtonPushedFcn = createCallbackFcn(app, @NewProcessingFunctionSubjectLevelButtonPushed, true);
            app.NewProcessingFunctionSubjectLevelButton.Text = 'New Processing Function: Subject Level';
            app.NewProcessingFunctionProjectLevelButton = uibutton(app.SetupTab, 'push');
            app.NewProcessingFunctionProjectLevelButton.Text = 'New Processing Function: Project Level';
            app.RunTab = uitab(app.TabGroup2);
            app.RunTab.Title = 'Run';
            app.DropDownLabel = uilabel(app.RunTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.RunTab);
            app.DropDown.Items = {'Group 1', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Group 1';
            app.RedoFcnLabel = uilabel(app.RunTab);
            app.RedoFcnLabel.Text = 'Redo Fcn';
            app.CheckBox_1 = uicheckbox(app.RunTab);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '1';
            app.RunGroupButton = uibutton(app.RunTab, 'push');
            app.RunGroupButton.Text = 'Run Group';
            app.SaveGroupButton = uibutton(app.RunTab, 'push');
            app.SaveGroupButton.Text = 'Save Group';
            app.RunAllButton = uibutton(app.RunTab, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Text = 'Run All';
            app.SaveAllButton = uibutton(app.RunTab, 'push');
            app.SaveAllButton.Text = 'Save All';
            app.CheckBox_2 = uicheckbox(app.RunTab);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.RunTab);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.RunTab);
            app.CheckBox_4.Text = '4';
            app.CheckBox_5 = uicheckbox(app.RunTab);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.RunTab);
            app.CheckBox_6.Text = '6';
            app.CheckBox_7 = uicheckbox(app.RunTab);
            app.CheckBox_7.Text = '7';
            app.CheckBox_8 = uicheckbox(app.RunTab);
            app.CheckBox_8.Text = '8';
            app.CheckBox_9 = uicheckbox(app.RunTab);
            app.CheckBox_9.Text = '9';
            app.CheckBox_10 = uicheckbox(app.RunTab);
            app.CheckBox_10.Text = '10';
            app.FunctionNamesTextArea_2Label = uilabel(app.RunTab);
            app.FunctionNamesTextArea_2Label.Text = 'FunctionNames';
            app.FunctionNamesTextArea_2 = uitextarea(app.RunTab);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.AllPlottingFunctionNamesTextAreaLabel = uilabel(app.PlotTab);
            app.AllPlottingFunctionNamesTextAreaLabel.Text = 'All Plotting Function Names';
            app.AllPlottingFunctionNamesTextArea = uitextarea(app.PlotTab);
            app.SelectaFunctionDropDownLabel = uilabel(app.PlotTab);
            app.SelectaFunctionDropDownLabel.Text = 'Select a Function';
            app.SelectaFunctionDropDown = uidropdown(app.PlotTab);
            app.MultipleTrialsCheckBox = uicheckbox(app.PlotTab);
            app.MultipleTrialsCheckBox.Text = 'Multiple Trials';
            app.AxisLabelsCheckBox = uicheckbox(app.PlotTab);
            app.AxisLabelsCheckBox.Text = 'Axis Labels';
            app.PlotButton = uibutton(app.PlotTab, 'push');
            app.PlotButton.Text = 'Plot';
            app.SaveFigCheckBox = uicheckbox(app.PlotTab);
            app.SaveFigCheckBox.Text = 'Save Fig';
            app.SavePNGCheckBox = uicheckbox(app.PlotTab);
            app.SavePNGCheckBox.Text = 'Save PNG';
            app.SavetransparentPNGCheckBox = uicheckbox(app.PlotTab);
            app.SavetransparentPNGCheckBox.Text = 'Save transparent PNG';
            app.XLimitsEditFieldLabel = uilabel(app.PlotTab);
            app.XLimitsEditFieldLabel.Text = 'X Limits';
            app.XLimitsEditField = uieditfield(app.PlotTab, 'text');
            app.XLimitsEditField.Value = 'all, OR subject, OR condition';
            app.YLimitsEditFieldLabel = uilabel(app.PlotTab);
            app.YLimitsEditFieldLabel.Text = 'Y Limits';
            app.YLimitsEditField = uieditfield(app.PlotTab, 'text');
            app.YLimitsEditField.Value = 'OR subjectCondition, OR each';
            app.ZLimitsEditFieldLabel = uilabel(app.PlotTab);
            app.ZLimitsEditFieldLabel.Text = 'Z Limits';
            app.ZLimitsEditField = uieditfield(app.PlotTab, 'text');
            app.NewPlottingFunctionButton = uibutton(app.PlotTab, 'push');
            app.NewPlottingFunctionButton.Text = 'New Plotting Function';
            app.RootSaveFolderEditFieldLabel = uilabel(app.PlotTab);
            app.RootSaveFolderEditFieldLabel.Text = 'Root Save Folder';
            app.RootSaveFolderEditField = uieditfield(app.PlotTab, 'text');
            app.RootSaveFolderEditField.Value = 'Full Folder Path';
            app.CurrentSaveSubfolderEditFieldLabel = uilabel(app.PlotTab);
            app.CurrentSaveSubfolderEditFieldLabel.Text = 'Current Save Subfolder';
            app.CurrentSaveSubfolderEditField = uieditfield(app.PlotTab, 'text');
            app.CurrentSaveSubfolderEditField.Value = 'Folder Name';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end